classdef matlab_basic_plotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Plot3dAxes  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotshape(app,p,cm)
            [numtri,~] = size(cm);
            for tri = 1:numtri
                ptri(:,1) = p(cm(tri,1)+1,:);
                ptri(:,2) = p(cm(tri,2)+1,:);
                ptri(:,3) = p(cm(tri,3)+1,:);
                ptri(:,4) = p(cm(tri,1)+1,:);
                x=ptri(1,:);
                y=ptri(2,:);
                z=ptri(3,:);
                line(app.Plot3dAxes,x,y,z)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            load("python_outputs.mat");

            
            dacc(dacc==0)=1; % extend rays
            
            rdacc = repmat(dacc,3,1)';
            dir = upacc.*rdacc;
            
            quiver3(app.Plot3dAxes,pacc(:,1),pacc(:,2),pacc(:,3),dir(:,1),dir(:,2),dir(:,3),0)
            
            plotshape(app,sp,cm)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot3dAxes = uiaxes(app.UIFigure);
            title(app.Plot3dAxes, 'Plot 3D')
            xlabel(app.Plot3dAxes, 'X')
            ylabel(app.Plot3dAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_basic_plotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end